classdef scratch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ControlCenterPanel          matlab.ui.container.Panel
        ExportButton                matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        BounaryConditionPanel       matlab.ui.container.Panel
        BottomValueEditField        matlab.ui.control.NumericEditField
        BottomValueEditFieldLabel   matlab.ui.control.Label
        TopValueEditField           matlab.ui.control.NumericEditField
        TopValueEditFieldLabel      matlab.ui.control.Label
        LeftValueEditField          matlab.ui.control.NumericEditField
        LeftValueEditFieldLabel     matlab.ui.control.Label
        BottomWallDropDown          matlab.ui.control.DropDown
        BottomWallDropDownLabel     matlab.ui.control.Label
        LeftWallDropDown            matlab.ui.control.DropDown
        LeftWallDropDownLabel       matlab.ui.control.Label
        TopWallDropDown             matlab.ui.control.DropDown
        TopWallDropDownLabel        matlab.ui.control.Label
        RightValueEditField         matlab.ui.control.NumericEditField
        RightValueEditFieldLabel    matlab.ui.control.Label
        RightWallDropDown           matlab.ui.control.DropDown
        RightWallDropDownLabel      matlab.ui.control.Label
        UserInputPanel              matlab.ui.container.Panel
        TolerenceEditField          matlab.ui.control.NumericEditField
        TolerenceEditFieldLabel     matlab.ui.control.Label
        MaxIterationEditField       matlab.ui.control.NumericEditField
        MaxIterationEditFieldLabel  matlab.ui.control.Label
        OmegaEditField              matlab.ui.control.NumericEditField
        OmegaEditFieldLabel         matlab.ui.control.Label
        SolverDropDown              matlab.ui.control.DropDown
        SolverDropDownLabel         matlab.ui.control.Label
        EpsEditField                matlab.ui.control.NumericEditField
        EpsEditFieldLabel           matlab.ui.control.Label
        GridEditField               matlab.ui.control.NumericEditField
        GridEditFieldLabel          matlab.ui.control.Label
        DomainEditField             matlab.ui.control.NumericEditField
        DomainEditFieldLabel        matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ChargeEditorTab             matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        ConvergenceTab              matlab.ui.container.Tab
        UIAxes6                     matlab.ui.control.UIAxes
        PotentialMapTab             matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        Potential3DTab              matlab.ui.container.Tab
        UIAxes4                     matlab.ui.control.UIAxes
        ElectricFieldTab            matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Solver state
        stop_req logical = false
        pause_req logical = false
        is_running logical = false

        % Grid & physics
        Lx
        Nx
        h
        epsilon

        % Fields
        V
        rho
        charges
        errorTrack
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            % -------- Default values --------
            app.DomainEditField.Value        = 100;
            app.GridEditField.Value          = 101;
            app.EpsEditField.Value           = 1.0;
            app.SolverDropDown.Value         = 'SOR';
            app.OmegaEditField.Value         = 1.8;
            app.MaxIterationEditField.Value  = 20000;
            app.TolerenceEditField.Value     = 1e-6;

            % Initialize the solver variables (Nx, V, h, etc.)
            resetSolver(app);

            % -------- Button callbacks --------
            app.RunButton.ButtonPushedFcn   = @(~,~) runSolver(app);
            app.PauseButton.ButtonPushedFcn = @(~,~) togglePause(app);
            app.ResetButton.ButtonPushedFcn = @(~,~) resetSolver(app);
            app.ExportButton.ButtonPushedFcn = @(~,~) exportData(app);

            % -------- Axes interactivity --------
            app.UIAxes.PickableParts = 'all';
            app.UIAxes.HitTest       = 'on';
            app.UIAxes.ButtonDownFcn = @(src,event) UIAxesButtonDown(app,event);

            disp("startupFcn initialized successfully")

        end

        function exportData(app)

            folder = uigetdir(pwd,'Select Folder to Save Graphs');
            if folder == 0
                return
            end

            % Potential Map
            f1 = figure('Visible','off');
            imagesc(app.V); set(app.UIAxes,'YDir','normal');
            axis equal tight; colorbar;
            title('Potential Map');
            exportgraphics(f1, fullfile(folder,'PotentialMap.png'));
            close(f1);

            % 3D Potential
            f2 = figure('Visible','off');
            surf(app.V,'EdgeColor','none');
            title('3D Potential');
            exportgraphics(f2, fullfile(folder,'Potential3D.png'));
            close(f2);

            % Electric Field
            f3 = figure('Visible','off');
            [Ex,Ey] = gradient(-app.V,app.h);
            quiver(Ey,Ex);
            axis equal tight;
            title('Electric Field');
            exportgraphics(f3, fullfile(folder,'ElectricField.png'));
            close(f3);

            % Convergence
            if ~isempty(app.errorTrack)
                f4 = figure('Visible','off');
                semilogy(app.errorTrack,'r');
                grid on
                title('Convergence');
                exportgraphics(f4, fullfile(folder,'Convergence.png'));
                close(f4);
            end

            uialert(app.UIFigure,'Graphs exported successfully!','Export Complete');
        end


        function togglePause(app)
            app.pause_req = ~app.pause_req;
            if app.pause_req
                app.PauseButton.Text = 'Resume';
            else
                app.PauseButton.Text = 'Pause';
            end
        end

        function resetSolver(app)
            app.stop_req = true;
            app.pause_req = false;
            app.is_running = false;

            app.Lx = app.DomainEditField.Value;
            app.Nx = max(2, app.GridEditField.Value); % Ensure at least 2 grid points
            app.epsilon = app.EpsEditField.Value;
            app.h = app.Lx / (app.Nx - 1);

            app.V = zeros(app.Nx, app.Nx);
            app.rho = zeros(app.Nx, app.Nx);
            app.charges = [];
            app.errorTrack = [];

            app.updatePlots();
            app.RunButton.Text = 'Run';
            app.RunButton.BackgroundColor = [0.96, 0.96, 0.96];

            %Update
            app.errorTrack = [];
            cla(app.UIAxes6);        % clear convergence graph
            app.DomainEditField.Value = 100;   % reset domain
            app.GridEditField.Value = 101;
            app.EpsEditField.Value = 1.0;
            app.updatePlots();
            drawnow;


        end

        function UIAxesButtonDown(app, event)
            if app.is_running, return; end

            i = round(event.IntersectionPoint(1));
            j = round(event.IntersectionPoint(2));

            % Clamp to valid indices
            i = max(1, min(app.Nx, i));
            j = max(1, min(app.Nx, j));

            if i < 2 || j < 2 || i > app.Nx-1 || j > app.Nx-1
                return
            end

            % ----- Check if clicking existing charge -----
            if ~isempty(app.charges)
                d = hypot(app.charges(:,1)-i, app.charges(:,2)-j);
                [dmin, idx] = min(d);

                if dmin <= 1
                    app.selectedChargeIdx = idx;
                    app.isDragging = true;
                    app.UIFigure.WindowButtonMotionFcn = @(~,~) dragCharge(app);
                    app.UIFigure.WindowButtonUpFcn = @(~,~) stopDrag(app);
                    return
                end
            end

            % ----- Create new charge -----
            res = inputdlg('Charge magnitude (q):','Input',[1 30],{'1.0'});
            if isempty(res), return; end
            q = str2double(res{1});

            app.rho(i,j) = app.rho(i,j) + q/(app.h^2);
            app.charges = [app.charges; i j q];
            app.updatePlots();
        end


        function updatePlots(app)
            % 1. Charge Editor Tab
            % Create the image and capture the handle 'im'
            im = imagesc(app.UIAxes,  app.V);
            set(app.UIAxes,'YDir','normal');
            axis(app.UIAxes, 'xy', 'equal', 'tight');
            % update 
            app.UIAxes.XLim = [1 app.Nx];
            app.UIAxes.YLim = [1 app.Nx];


            % IMPORTANT: Assign the click function to the IMAGE, not just the axes
            im.ButtonDownFcn = @(src, event) app.UIAxesButtonDown(event);

            hold(app.UIAxes, 'on');
            if ~isempty(app.charges)
                % Plot existing charges
                px = (app.charges(:,1)-1)*app.h;
                py = (app.charges(:,2)-1)*app.h;

                plot(app.UIAxes, px, py, 'wo','MarkerSize',10,'LineWidth',2,'HitTest','off');

                % Label them
                for k = 1:size(app.charges, 1)
                    tx = (app.charges(k,1)-1)*app.h;
                    ty = (app.charges(k,2)-1)*app.h;

                    text(app.UIAxes, tx+app.h, ty+app.h, num2str(app.charges(k,3)), ...
                        'Color','w', ...
                        'FontWeight','bold', ...
                        'FontSize',11, ...
                        'HorizontalAlignment','left', ...
                        'VerticalAlignment','bottom', ...
                        'Clipping','on', ...
                        'HitTest','off');
                end
            end
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Charge Editor (Click to Place)');

            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Charge Editor (Click to Place)');

            % 2. Potential Map
            imagesc(app.UIAxes2, app.V); 
            colorbar(app.UIAxes2);
            set(app.UIAxes,'YDir','normal');

            axis(app.UIAxes2, 'xy', 'equal', 'tight');
            title(app.UIAxes2, 'Potential Heatmap');
            app.UIAxes.XLim = [1 app.Nx];
            app.UIAxes.YLim = [1 app.Nx];

            % 3. Potential 3D
            surf(app.UIAxes4,app.V, 'EdgeColor', 'none');
            title(app.UIAxes4, '3D Potential Surface');

            % 4. Electric Field
            [Ex, Ey] = gradient(-app.V, app.h);
            scale = 300/app.Nx;   % adaptive scaling
            quiver(app.UIAxes5, Ey,Ex, scale, 'LineWidth',1);
            axis(app.UIAxes5, 'xy', 'equal', 'tight');
            title(app.UIAxes5, 'Electric Field Vectors');

            % 5. Convergence
            if ~isempty(app.errorTrack)
                plot(app.UIAxes6, app.errorTrack, 'r', 'LineWidth', 1.5);
                set(app.UIAxes6, 'YScale', 'log'); grid(app.UIAxes6, 'on');
                title(app.UIAxes6, 'Error vs Iteration');
            end
        end

        function current = getCurrentTab(app)
            current = app.TabGroup.SelectedTab.Title;
        end

        function updateActivePlot(app)

            tab = app.getCurrentTab();

            switch tab
                case 'Charge Editor'
                    imagesc(app.UIAxes, app.V); set(app.UIAxes,'YDir','normal');
                    axis(app.UIAxes,'xy','equal','tight');

                case 'Potential Map'
                    imagesc(app.UIAxes2, app.V); set(app.UIAxes,'YDir','normal');
                    colorbar(app.UIAxes2);

                case 'Potential 3D'
                    surf(app.UIAxes4, app.V,'EdgeColor','none');

                case 'Electric Field'
                    [Ex,Ey]=gradient(-app.V,app.h);
                    quiver(app.UIAxes5,Ey, Ex);

                case 'Convergence'
                    plot(app.UIAxes6, app.errorTrack,'r'); set(app.UIAxes6,'YScale','log');
            end
        end

        function runSolver(app)
            if app.is_running, app.stop_req = true; return; end
            
            app.is_running = true;
            app.stop_req = false;
            app.RunButton.Text = 'Stop';
            app.RunButton.BackgroundColor = [1 0.7 0.7];
            
            % Get params
            method = app.SolverDropDown.Value;
            omega = app.OmegaEditField.Value;
            tol = app.TolerenceEditField.Value;
            maxI = app.MaxIterationEditField.Value;
            app.errorTrack = nan(maxI, 1);
            
            % Boundary conditions mapping
            % user dropdowns use "Jacobian", "Neuman"
            bc_types = {app.LeftWallDropDown.Value, app.RightWallDropDown.Value, app.TopWallDropDown.Value, app.BottomWallDropDown.Value};
            bc_vals  = [app.LeftValueEditField.Value, app.RightValueEditField.Value,app.TopValueEditField.Value, app.BottomValueEditField.Value];

            for iter = 1:maxI
                if app.stop_req, break; end
                while app.pause_req, pause(0.2); drawnow; end
                
                V_old = app.V;
                
                % Neumann
                if strcmp(bc_types{1}, 'Neuman'), app.V(:,1) = app.V(:,2) + app.h * bc_vals(1); end
                if strcmp(bc_types{2}, 'Neuman'), app.V(:,app.Nx) = app.V(:,app.Nx-1) + app.h * bc_vals(2); end
                if strcmp(bc_types{3}, 'Neuman'), app.V(1,:) = app.V(2,:) + app.h * bc_vals(3); end
                if strcmp(bc_types{4}, 'Neuman'), app.V(app.Nx,:) = app.V(app.Nx-1,:) + app.h * bc_vals(4); end

                % Iterative Step
                if strcmp(method, 'Jacobian')
                    V_new = app.V;
                    for i = 2:app.Nx-1
                        for j = 2:app.Nx-1
                            V_new(i,j) = 0.25*(V_old(i+1,j)+V_old(i-1,j)+V_old(i,j+1)+V_old(i,j-1) + (app.h^2/app.epsilon)*app.rho(i,j));
                        end
                    end
                    app.V = V_new;
                elseif strcmp(method, 'Gauss-Siedal')
                    for i = 2:app.Nx-1
                        for j = 2:app.Nx-1
                            app.V(i,j) = 0.25*(app.V(i+1,j)+app.V(i-1,j)+app.V(i,j+1)+app.V(i,j-1) + (app.h^2/app.epsilon)*app.rho(i,j));
                        end
                    end
                else % SOR
                    for i = 2:app.Nx-1
                        for j = 2:app.Nx-1
                            V_star = 0.25*(app.V(i+1,j)+app.V(i-1,j)+app.V(i,j+1)+app.V(i,j-1) + (app.h^2/app.epsilon)*app.rho(i,j));
                            app.V(i,j) = (1-omega)*app.V(i,j) + omega*V_star;
                        end
                    end
                end

                % Dirichlet
                if strcmp(bc_types{1}, 'Dirichlet'), app.V(:,1) = bc_vals(1); end
                if strcmp(bc_types{2}, 'Dirichlet'), app.V(:,app.Nx) = bc_vals(2); end
                if strcmp(bc_types{3}, 'Dirichlet'), app.V(1,:) = bc_vals(3); end
                if strcmp(bc_types{4}, 'Dirichlet'), app.V(app.Nx,:) = bc_vals(4); end

                % Calculate Error
                % Use a name other than 'diff' to avoid conflict with built-in function
                err = max(abs(app.V(:) - V_old(:)));

                % Safety check: If err is empty (happens if V is not initialized),
                % set it to a high value so the loop doesn't crash.
                if isempty(err)
                    err = Inf;
                end

                app.errorTrack(iter) = err;

                % Update plots every 50 iterations
                if mod(iter, 200) == 0
                    updateActivePlot(app);
                    drawnow limitrate;
                end


                % Convergence check
                if err < tol
                    % Trim the errorTrack if it finished early
                    app.errorTrack(iter+1:end) = [];
                    break;
                end
            end
            
            app.updatePlots();
            app.is_running = false;
            app.RunButton.Text = 'Run';
            app.RunButton.BackgroundColor = [0.96 0.96 0.96];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                startupFcn(app)   % REQUIRED
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChargeEditorTab = uitab(app.TabGroup);
            app.ChargeEditorTab.Title = 'Charge Editor';
            app.UIAxes = uiaxes(app.ChargeEditorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConvergenceTab = uitab(app.TabGroup);
            app.ConvergenceTab.Title = 'Convergence';
            app.UIAxes6 = uiaxes(app.ConvergenceTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.PotentialMapTab = uitab(app.TabGroup);
            app.PotentialMapTab.Title = 'Potential Map';
            app.UIAxes2 = uiaxes(app.PotentialMapTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Potential3DTab = uitab(app.TabGroup);
            app.Potential3DTab.Title = 'Potential 3D';
            app.UIAxes4 = uiaxes(app.Potential3DTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ElectricFieldTab = uitab(app.TabGroup);
            app.ElectricFieldTab.Title = 'Electric Field';
            app.UIAxes5 = uiaxes(app.ElectricFieldTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UserInputPanel = uipanel(app.UIFigure);
            app.UserInputPanel.Title = 'User Input';
            app.DomainEditFieldLabel = uilabel(app.UserInputPanel);
            app.DomainEditFieldLabel.Text = 'Domain';
            app.DomainEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.GridEditFieldLabel = uilabel(app.UserInputPanel);
            app.GridEditFieldLabel.Text = 'Grid';
            app.GridEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.EpsEditFieldLabel = uilabel(app.UserInputPanel);
            app.EpsEditFieldLabel.Text = 'Eps';
            app.EpsEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.SolverDropDownLabel = uilabel(app.UserInputPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.UserInputPanel);
            app.SolverDropDown.Items = {'Jacobian', 'Gauss-Siedal', 'SOR'};
            app.SolverDropDown.Value = 'Jacobian';
            app.OmegaEditFieldLabel = uilabel(app.UserInputPanel);
            app.OmegaEditFieldLabel.Text = 'Omega';
            app.OmegaEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.MaxIterationEditFieldLabel = uilabel(app.UserInputPanel);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.TolerenceEditFieldLabel = uilabel(app.UserInputPanel);
            app.TolerenceEditFieldLabel.Text = 'Tolerence';
            app.TolerenceEditField = uieditfield(app.UserInputPanel, 'numeric');
            app.BounaryConditionPanel = uipanel(app.UIFigure);
            app.BounaryConditionPanel.Title = 'Bounary Condition';
            app.RightWallDropDownLabel = uilabel(app.BounaryConditionPanel);
            app.RightWallDropDownLabel.Text = 'Right Wall';
            app.RightWallDropDown = uidropdown(app.BounaryConditionPanel);
            app.RightWallDropDown.Items = {'Dirichlet', 'Neuman'};
            app.RightWallDropDown.Value = 'Dirichlet';
            app.RightValueEditFieldLabel = uilabel(app.BounaryConditionPanel);
            app.RightValueEditFieldLabel.Text = 'Right Value';
            app.RightValueEditField = uieditfield(app.BounaryConditionPanel, 'numeric');
            app.TopWallDropDownLabel = uilabel(app.BounaryConditionPanel);
            app.TopWallDropDownLabel.Text = 'Top Wall';
            app.TopWallDropDown = uidropdown(app.BounaryConditionPanel);
            app.TopWallDropDown.Items = {'Dirichlet', 'Neuman'};
            app.TopWallDropDown.Value = 'Dirichlet';
            app.LeftWallDropDownLabel = uilabel(app.BounaryConditionPanel);
            app.LeftWallDropDownLabel.Text = 'Left Wall';
            app.LeftWallDropDown = uidropdown(app.BounaryConditionPanel);
            app.LeftWallDropDown.Items = {'Dirichlet', 'Neuman'};
            app.LeftWallDropDown.Value = 'Dirichlet';
            app.BottomWallDropDownLabel = uilabel(app.BounaryConditionPanel);
            app.BottomWallDropDownLabel.Text = 'Bottom Wall';
            app.BottomWallDropDown = uidropdown(app.BounaryConditionPanel);
            app.BottomWallDropDown.Items = {'Dirichlet', 'Neuman'};
            app.BottomWallDropDown.Value = 'Dirichlet';
            app.LeftValueEditFieldLabel = uilabel(app.BounaryConditionPanel);
            app.LeftValueEditFieldLabel.Text = 'Left Value';
            app.LeftValueEditField = uieditfield(app.BounaryConditionPanel, 'numeric');
            app.TopValueEditFieldLabel = uilabel(app.BounaryConditionPanel);
            app.TopValueEditFieldLabel.Text = 'Top Value';
            app.TopValueEditField = uieditfield(app.BounaryConditionPanel, 'numeric');
            app.BottomValueEditFieldLabel = uilabel(app.BounaryConditionPanel);
            app.BottomValueEditFieldLabel.Text = 'Bottom Value';
            app.BottomValueEditField = uieditfield(app.BounaryConditionPanel, 'numeric');
            app.ControlCenterPanel = uipanel(app.UIFigure);
            app.ControlCenterPanel.Title = 'Control Center';
            app.RunButton = uibutton(app.ControlCenterPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.ControlCenterPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.ControlCenterPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.ExportButton = uibutton(app.ControlCenterPanel, 'push');
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scratch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end